classdef EFiSketch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        PlaceNodesButton        matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        Panel3                  matlab.ui.container.Panel
        LabelNumericEditField   matlab.ui.control.Label
        NumberOfNodeEditField   matlab.ui.control.NumericEditField
        RandomGenerateButton    matlab.ui.control.Button
        LabelTextArea3          matlab.ui.control.Label
        CoordinatesEditField    matlab.ui.control.TextArea
        UIAxes                  matlab.ui.control.UIAxes
        Tab2                    matlab.ui.container.Tab
        Panel2                  matlab.ui.container.Panel
        ButtonGroup             matlab.ui.container.ButtonGroup
        RadioButton             matlab.ui.control.RadioButton
        RadioButton2            matlab.ui.control.RadioButton
        RadioButton3            matlab.ui.control.RadioButton
        ButtonGroup2            matlab.ui.container.ButtonGroup
        RadioButton4            matlab.ui.control.RadioButton
        RadioButton5            matlab.ui.control.RadioButton
        RadioButton6            matlab.ui.control.RadioButton
        RadioButton7            matlab.ui.control.RadioButton
        Label3                  matlab.ui.control.Label
        NumericEditField6       matlab.ui.control.NumericEditField
        LabelDropDown3          matlab.ui.control.Label
        DropDown3               matlab.ui.control.DropDown
        Panel4                  matlab.ui.container.Panel
        LabelDropDown2          matlab.ui.control.Label
        DropDown2               matlab.ui.control.DropDown
        Label5                  matlab.ui.control.Label
        NumericEditField8       matlab.ui.control.NumericEditField
        LabelDropDown4          matlab.ui.control.Label
        DropDown4               matlab.ui.control.DropDown
        LabelDropDown           matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Tab3                    matlab.ui.container.Tab
        Button3                 matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        Button4                 matlab.ui.control.Button
        LabelNumericEditField9  matlab.ui.control.Label
        IterationEditField      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Coordinates; % Coordinates of WiFi nodes
        NumberOfNodes; % Numer of Nodes
        Iterations;
        Markersizes;
        ABSConfig;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Please remember to add every value that can be used in the GUI here.
            app.NumberOfNodes = app.NumberOfNodeEditField.Value;
            
            tempPosNodes = app.CoordinatesEditField.Value;
            posNodes = zeros(numel(tempPosNodes),2);
            for i = 1 : numel(tempPosNodes)
                posNodes(i,1:2) = str2num(cell2mat(tempPosNodes(i)));
            end
            
            app.Coordinates = posNodes;
            app.Iterations = app.IterationEditField.Value;
            
            app.Markersizes = 6;
            
            
            %% These are the initialzed x/y lims for plots
            ylim(app.UIAxes2,[-5e-5,5e-5])
            xlim(app.UIAxes3,[0,60])
            ylim(app.UIAxes3,[-60,60])
            xlim(app.UIAxes,[0,60])
            ylim(app.UIAxes,[-60,60])
            
            app.ABSConfig = 1;
            
        end

        % Button pushed function: Button3
        function ButtonStartSimulationButtonPushed(app, event)
            posNodes = app.Coordinates;
            Nnodes = app.NumberOfNodes;
            %% PSR Calculation from AP to each node, and iterate 200 times
            % This part is to set the parameters of simulation, including LTE and WiFi
            % and E-Fi parameters. Some parameters shall be modified by the users via
            % GUI design
            
            %========================== CONFIG LTE PARAMETERS ========================%
            nDLRB = 100;              % Number Resource Blocks: 6,15,25,50,75,100
            cqiPeriod = 8;            % CQI Report Periodicity (2,5,10, 16, 20,
            % 32, 40, 64, 80, app.Markersizes8 or 160 subfrms)
            % TS36.101 Table 9.3.2.1.1-1 establishes 8ms
            cqiOffset = 3;            % Offset deriving from cqi-pmi-ConfigurationIndex
            % Make sure that the offset is not included in the
            % ABSList
            cqiCorrection = 4;        % CQI selection seem to be higher than need it,
            % introducing errors in the frames. Thus,
            % decreasing the throughput.
            numFrames = 1;            % Number of LTE Frames Generated
            ABSConfig = app.ABSConfig;            % ABS Configuration, 0, 1, 5
            %=========================================================================%
            %========================== CONFIG WiFi PARAMETERS =======================%
            config = 'HT';            % NonHT (802.11a/b/g), HT (802.11n), VHT (802.11ac)
            chBandwidth = 'CBW20';    % CBW20, CBW40, CBW80, CBW160
            modulation = 'OFDM';      % OFDM or DSSS
            nAntennas = 1;            % Number of Transmitting Antennas
            spatialStreams = 1;       % Number of Spatial Streams
            MCS = 0;                  % MCS for 802.11 legacy:
            %  from 00 to 07 have 1 spatial stream.
            % MCS for 802.11n:
            %  from 00 to 07 have 1 spatial stream.
            %  from 08 to 15 have 2 spatial streams.
            %  from 16 to 23 have 3 spatial streams.
            %  from 24 to 31 have 4 spatial streams.
            % MCS for 802.11ac:
            %  from 00 to 09 have 1 spatial stream.
            % PSDULength = 100;         % Length of the PSDU Field
            nWiFiFrames = 1;          % Number of frames Being Transmitted
            wiFiFrameStride = 20e-6;  % Interval between frames in seconds
            %-------------------------------------------------------------------------%
            [PSDULength] = wifi_config(MCS);  % Length of the PSDU Field
            %=========================================================================%
            %========================== CONFIG LTE-WiFi ==============================%
            Pnoise = -98;             % Noise Power (dBm)
            Ptx_LTE = 17;             % Transmitted Power for LTE in dBm
            Ptx_WiFi = 17;            % Transmitted Power for WiFi in dBm
            delayProfile = 'Model-B'; % 802.11n  Tgn Models: A, B, C, D or E
            % 802.11ac Tgn Models: A, B, C, D or E
            PSR_th = 0.8;             % Defines the coverage area
            K = 1;                    % Maximum Number of WiFi-Direct Connections
            % ------------------------------------------------------------------------%
            posAP = [0 0];              % Location of the AP
            posBS = [60 0];             % Location of the BS
            
            % pGO = [-20 -40];          % Location of a Group owner. The Group owner acts
            % as a relay serving de WiFi Device. The Relay
            % assignation is not covered in this code, but it
            % can be used to understand the improvement on
            % the performance.
            
            % ------------------------------------------------------------------------%
            % LTE Configuration Parameters
            [ABSList, fsLTE, enbConfig, channel, cec] = lte_config(ABSConfig, nDLRB,...
                cqiOffset, delayProfile);
            % Subframes to run the experiments over
            if length(ABSList)>1; ABSList_loop = 0:(10*numFrames-1);
            else                  ABSList_loop = (ABSList:10:10*numFrames-1);
            end
            %=========================================================================%
            %====================== CONFIG AND RUN ITERATION(S) ======================%
            Iteration = app.Iterations;
            PSR_AP_to_EachNode = zeros(Nnodes, Iteration);
            
            %=========================================================================%
            %====================== CONFIG AND RUN ITERATION(S) ======================%
            cla(app.UIAxes3);
            
            
            hold(app.UIAxes3, 'on');
            
            
            R = 60;
            x1=0:.01:R;
            y1=[sqrt(R.^2-x1.^2) ; -sqrt(R.^2-x1.^2)];
            
            
            % Plot Coverage Area under LTE Interference
            a=25;  % horizontal radius
            b=30;  % vertical radius
            x0=0;  % x0,y0 ellipse centre coordinates
            y0=0;
            t=-pi:0.01:pi;
            x2=x0+a*cos(t);
            y2=y0+b*sin(t);
            
            
            for node = 1 : Nnodes
                for iter = 1 : Iteration
                    posDev = posNodes(node,:);           % Location of the WiFi Device
                    distance_LTE = sqrt((posDev(1)-posBS(1)).^2 + (posDev(2)-posBS(2)).^2);
                    distance_WiFi = sqrt((posDev(1)-posAP(1)).^2 + (posDev(2)-posAP(2)).^2);
                    LTE_WiFi_TxChRx;
                    PSR_AP_to_EachNode(node, iter) = pkt_ok_tot/(numFrames*nWiFiFrames);
                    
                    cla(app.UIAxes2);
                    
                    plot(app.UIAxes2, real(overlap_reshape));
                    hold(app.UIAxes2, 'on');
                    plot(app.UIAxes2, real(lte_rxWaveform_awgn));
                    
                    cla(app.UIAxes3);
                    
                    plot(app.UIAxes3, x1,y1,'Color','k');
                    plot(app.UIAxes3, x2,y2,'Color','k','LineStyle','--');
                    plot(app.UIAxes3,posNodes(:,1),posNodes(:,2),'o','Color','b', 'MarkerSize', app.Markersizes)
                    plot(app.UIAxes3,posNodes(node,1),posNodes(node,2),'o','Color','blue', 'MarkerFaceColor', 'blue'...
                        ,'MarkerSize', app.Markersizes)
                    plot(app.UIAxes3,0,0,'s','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', 'blue')
                    plot(app.UIAxes3,60,0,'d','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', [1 .5 0])
                    
                end
            end
            
            PSR_AP_to_EachNode_Average = mean(PSR_AP_to_EachNode, 2);
            % PSR_AP_to_EachNode_Average = rand(20,1);
            % PSR_AP_to_EachNode_Average = [ones(1,10) zeros(1,10)]';
            
            hold(app.UIAxes3, 'off');


            %% Select potential nodes for WiFi Direct Client (WDC) and Relay Candidate (RC)
            
            potential_RC_positions  = posNodes(PSR_AP_to_EachNode_Average>=PSR_th,:);
            potential_RC_index = find(PSR_AP_to_EachNode_Average>=PSR_th);
            NpotentialRC  = numel(potential_RC_index);
            
            
            potential_WDC_positions = posNodes(PSR_AP_to_EachNode_Average<PSR_th,:);
            potential_WDC_index = find(PSR_AP_to_EachNode_Average<PSR_th);
            NpotentialWDC = numel(potential_WDC_index);
            
            %% For each pair of potential RC <-> WDC, calculate their PSR
            
            if (NpotentialRC ~= 0 && NpotentialWDC ~= 0)
                
                PSR_WDC_RC = zeros(NpotentialRC, NpotentialWDC, Iteration);
                
                hold(app.UIAxes3, 'on');
                xlim(app.UIAxes3,[0,60])
                ylim(app.UIAxes3,[-60,60])
                
                
                for i = 1 : NpotentialRC
                    for j = 1 : NpotentialWDC
                        for iter = 1 : Iteration
                            posDev_1 = potential_RC_positions(i,:);            %Relay Candidate position
                            posDev_2 = potential_WDC_positions(j,:);           %WiFi Direct Client position
                            distance_WiFi = sqrt((posDev_1(1)-posDev_2(1)).^2 + (posDev_1(2)-posDev_2(2)).^2);
                            distance_LTE = sqrt((posDev_1(1)-posBS(1)).^2 + (posDev_1(2)-posBS(2)).^2);
                            LTE_WiFi_TxChRx;
                            PSR_WDC_RC(i, j, iter) = pkt_ok_tot/(numFrames*nWiFiFrames);
                            
                            cla(app.UIAxes2);
                            
                            plot(app.UIAxes2, real(overlap_reshape));
                            hold(app.UIAxes2, 'on');
                            plot(app.UIAxes2, real(lte_rxWaveform_awgn));
                            
                            cla(app.UIAxes3);
                            plot(app.UIAxes3, x1,y1,'Color','k');
                            plot(app.UIAxes3, x2,y2,'Color','k','LineStyle','--');
                            
                            plot(app.UIAxes3,posNodes(potential_WDC_index,1),posNodes(potential_WDC_index,2),'o','Color','r', 'MarkerSize', app.Markersizes)
                            plot(app.UIAxes3,posNodes(potential_RC_index,1),posNodes(potential_RC_index,2),'o','Color','b', 'MarkerSize', app.Markersizes)
                            
                            plot(app.UIAxes3,potential_RC_positions(i,1),potential_RC_positions(i,2),'o','Color','b', 'MarkerFaceColor', 'blue'...
                                , 'MarkerSize', app.Markersizes)
                            plot(app.UIAxes3,potential_WDC_positions(j,1),potential_WDC_positions(j,2),'o','Color','r', 'MarkerFaceColor', 'red'...
                                , 'MarkerSize', app.Markersizes)
                            
                            plot(app.UIAxes3,0,0,'s','Color','k', 'MarkerSize', app.Markersizes, 'MarkerFaceColor', 'blue')
                            plot(app.UIAxes3,60,0,'d','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', [1 .5 0])
                            
                        end
                    end
                end
                
                PSR_WDC_RC_Aver = mean(PSR_WDC_RC,3);
                %     PSR_WDC_RC_Aver = rand(NpotentialRC,NpotentialWDC);
                
                hold(app.UIAxes3, 'off');
                %% Centralized Hungarian Algorithm
                GOCList = potential_RC_index';
                P2PCList = potential_WDC_index';
                W = [PSR_WDC_RC_Aver' PSR_AP_to_EachNode_Average(P2PCList)];
                %     W = rand(NpotentialWDC, NpotentialRC+1)';
                
                %% Initialization of the Modified Hungarian Algorithm
                M = length(GOCList); N = length(P2PCList);
                P = ceil(ones(size(W,1),size(W,2)) - (ones(size(W,1),size(W,2)) - W).^50);
                t = ones(1,size(P,1))*P;
                dummy_relays = t;
                
                % Dummy Relays insertion
                W_Mod = []; pVec = [];
                for col = 1:size(W,2)-1    %The last one belongs to the AP
                    W1 = repmat(W(:,col),1, min(dummy_relays(col),K));
                    W_Mod = [W_Mod W1];                                   %#ok<AGROW>
                    pV1 = repmat(GOCList(col), 1, min(dummy_relays(col),K));
                    pVec = [pVec pV1];                                    %#ok<AGROW>
                end
                
                % Dummy AP insertion
                W2 = repmat(W(:,size(W,2)), 1, Nnodes-M);
                W_Mod = [W_Mod W2];
                pV1 = repmat(-1, 1, Nnodes-M);
                pVec = [pVec pV1];
                
                %% Hungarian Algorithm
                W_Mod_reverse = ones(size(W_Mod,1),size(W_Mod,2)) - W_Mod;
                [ Assign , ~ ] = munkres(W_Mod_reverse);
                P2PGOList = pVec(Assign);           % (X,f(X)) = P2P X assigned to GOC f(X)
                
                %% Final PSR in the Network
                PSR_new = PSR_AP_to_EachNode_Average';
                
                % PSR for the P2PC
                PSR_new_p2p = [];
                for p2pc = 1:length(P2PCList)
                    if(P2PGOList(p2pc)~=-1)
                        loc_M = find(GOCList==P2PGOList(p2pc));
                        PSR_new_p2p = [PSR_new_p2p W(p2pc,loc_M)];        %#ok<AGROW>
                    else
                        PSR_new_p2p = [PSR_new_p2p W(p2pc,end)];          %#ok<AGROW>
                    end
                end
                PSR_new(P2PCList) = PSR_new_p2p;
                
                %% Final Node Categorization
                % P2PC Relaying
                relay = (1:Nnodes);
                relay(P2PCList) = P2PGOList;
                
                % Final Node Grouping
                relay_final = relay;
                loc = find(relay==-1);
                relay_final(loc) = loc;
                
                % Final Node Categorization
                % Clients N.S.Z
                loc = find(relay(P2PCList)==-1);
                Clients_NSZ = P2PCList(loc);
                % Clients S.Z VS GO
                Clients_SZ = []; GO = []; Psi = [];
                for goc = GOCList
                    Psi_List = find(relay==goc);
                    Psi_List(Psi_List==goc) = [];
                    if(isempty(Psi_List))
                        % Clients S.Z
                        Clients_SZ = [Clients_SZ goc];                    %#ok<AGROW>
                    else
                        % GO
                        GO  = [GO goc];                                   %#ok<AGROW>
                        Psi = [Psi length(Psi_List)];                     %#ok<AGROW>
                    end
                end
                % P2P Nodes
                P2P = setdiff((1:Nnodes), [Clients_SZ Clients_NSZ GO]);


            else
                %% Final Node Categorization
                fig = app.UIFigure;
                uialert(fig,'Empty Relay Candidate / Wi-Fi Direct Candidate list!','E-Fi Demonstration','Icon','error');
            end
            % Average PSR
            
            %             disp('==================Results==================');
            %             fprintf('WiFi Relay = %.1f%% \nWiFi Direct = %.1f%% \nClients in Safe Zone = %.1f%% \nClients not in Safe Zone = %.1f%%\n',...
            %                 100*length(GO)/Nnodes, 100*length(P2P)/Nnodes, ...
            %                 100*length(Clients_SZ)/Nnodes, 100*length(Clients_NSZ)/Nnodes);
            %             disp('===========================================');
            
            cla(app.UIAxes3);
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, x1,y1,'Color','k');
            plot(app.UIAxes3, x2,y2,'Color','k','LineStyle','--');
            
            plot(app.UIAxes3,0,0,'s','Color','k', 'MarkerSize', app.Markersizes, 'MarkerFaceColor', 'blue')
            plot(app.UIAxes3,60,0,'d','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', [1 .5 0])
            
            plot(app.UIAxes3, posNodes(GO,1),posNodes(GO,2),'o','Color', [53, 42, 134] / 255, 'MarkerFaceColor',[53, 42, 134] / 255, 'MarkerSize', app.Markersizes)
            plot(app.UIAxes3, posNodes(P2P,1), posNodes(P2P,2),'o','Color', [248, 250, 3] / 255, 'MarkerFaceColor',[248, 250, 3] / 255, 'MarkerSize', app.Markersizes)
            plot(app.UIAxes3, posNodes(Clients_SZ,1), posNodes(Clients_SZ,2),'o','Color', [6, 156, 207] / 255, 'MarkerFaceColor',[6, 156, 207] / 255, 'MarkerSize', app.Markersizes)
            plot(app.UIAxes3, posNodes(Clients_NSZ,1), posNodes(Clients_NSZ,2),'o','Color', [165, 190, 106] / 255, 'MarkerFaceColor',[165, 190, 106] / 255, 'MarkerSize', app.Markersizes)
            hold(app.UIAxes3, 'off');
            fig = app.UIFigure;
            uialert(fig,'Simulation successfully terminated.','E-Fi Demonstration','Icon','success');
            
        end

        % Value changed function: CoordinatesEditField
        function CoordinatesValueChanged(app, event)
            app.Coordinates = app.CoordinatesEditField.Value;
        end

        % Value changed function: DropDown4
        function DropDown4ValueChanged(app, event)
            value = app.DropDown4.Value;
            if value == 'ABS0'
                app.ABSConfig = 0;
            elseif value == 'ABS1'
                app.ABSConfig = 1;
            else
                app.ABSConfig = 5;
            end
        end

        % Value changed function: NumberOfNodeEditField
        function NumerOfNodesValueChanged(app, event)
            app.NumberOfNodes = app.NumberOfNodeEditField.Value;
        end

        % Value changed function: IterationEditField
        function NumericEditFieldIterationValueChanged(app, event)
            app.Iterations = app.IterationEditField.Value;
            
        end

        % Button pushed function: PlaceNodesButton
        function PlaceNodesButtonButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes3);
            tempPosNodes = app.CoordinatesEditField.Value;
            posNodes = zeros(numel(tempPosNodes),2);
            for i = 1 : numel(tempPosNodes)
                posNodes(i,1:2) = str2num(cell2mat(tempPosNodes(i)));
            end
            
            app.Coordinates = posNodes;
            
            hold(app.UIAxes, 'on');
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes,posNodes(:,1),posNodes(:,2),'o','Color','b', 'MarkerSize', app.Markersizes)
            plot(app.UIAxes3,posNodes(:,1),posNodes(:,2),'o','Color','b', 'MarkerSize', app.Markersizes)
            
            plot(app.UIAxes3,0,0,'s','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', 'blue')
            plot(app.UIAxes3,60,0,'d','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', [1 .5 0])
            
            plot(app.UIAxes,0,0,'s','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', 'blue')
            plot(app.UIAxes,60,0,'d','Color','k', 'MarkerSize', app.Markersizes,'MarkerFaceColor', [1 .5 0])
            
            R = 60;
            x=0:.01:R;
            y=[sqrt(R.^2-x.^2) ; -sqrt(R.^2-x.^2)];
            
            plot(app.UIAxes, x,y,'Color','k');
            plot(app.UIAxes3, x,y,'Color','k');
            % Plot Coverage Area under LTE Interference
            a=25;  % horizontal radius
            b=30;  % vertical radius
            x0=0;  % x0,y0 ellipse centre coordinates
            y0=0;
            t=-pi:0.01:pi;
            x=x0+a*cos(t);
            y=y0+b*sin(t);
            plot(app.UIAxes, x,y,'Color','k','LineStyle','--');
            plot(app.UIAxes3, x,y,'Color','k','LineStyle','--');


            hold(app.UIAxes, 'off');
            hold(app.UIAxes3, 'off');
        end

        % Button pushed function: RandomGenerateButton
        function RandomGenerateButtonButtonPushed(app, event)
            Nnodes = app.NumberOfNodes;           % Number of WiFi Devices
            minR = 0; maxR = 60;   % Max range and min range
            minAg = -pi/2;         % Max angle and min angle
            maxAg = pi/2;
            radius = (maxR-minR).*rand(Nnodes,1) + minR;
            angle = (maxAg-minAg).*rand(Nnodes,1) + minAg;
            posNodes = [radius.*cos(angle) radius.*sin(angle)];
            tempStr = strrep(mat2str(posNodes, 4), ';', '\n');
            tempStr = strrep(tempStr,']','');
            tempStr = strrep(tempStr,'[','');
            app.CoordinatesEditField.Value = sprintf(tempStr);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'E-Fi Demonstration';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'E-Fi Live Demonstration (GENESYS Lab, genesys-lab.org)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Simulation Architecture';
            app.PlaceNodesButton = uibutton(app.Tab, 'push');
            app.PlaceNodesButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceNodesButtonButtonPushed, true);
            app.PlaceNodesButton.Text = 'Place nodes';
            app.ResetButton = uibutton(app.Tab, 'push');
            app.ResetButton.Text = 'Reset';
            app.Panel3 = uipanel(app.Tab);
            app.Panel3.Title = 'Arrangement of WiFi Nodes';
            app.LabelNumericEditField = uilabel(app.Panel3);
            app.LabelNumericEditField.Text = 'Number of WiFi nodes ';
            app.NumberOfNodeEditField = uieditfield(app.Panel3, 'numeric');
            app.NumberOfNodeEditField.ValueChangedFcn = createCallbackFcn(app, @NumerOfNodesValueChanged, true);
            app.NumberOfNodeEditField.Limits = [5 20];
            app.NumberOfNodeEditField.Value = 20;
            app.RandomGenerateButton = uibutton(app.Panel3, 'push');
            app.RandomGenerateButton.ButtonPushedFcn = createCallbackFcn(app, @RandomGenerateButtonButtonPushed, true);
            app.RandomGenerateButton.Text = 'Random Generate';
            app.LabelTextArea3 = uilabel(app.Panel3);
            app.LabelTextArea3.Text = 'Coordinates of WiFi nodes (pairwise)';
            app.CoordinatesEditField = uitextarea(app.Panel3);
            app.CoordinatesEditField.ValueChangedFcn = createCallbackFcn(app, @CoordinatesValueChanged, true);
            app.CoordinatesEditField.Value = {'47.74 9.698'; '22.5 31.6'; '15.59 -30.29'; '38.23 -11.35'; '52.53 14.97'; '17.08 1.109'; '33.16 -22.2'; '49.16 30.51'; '17.14 8.775'; '5.247 -51.2'; '38.64 -44.78'; '29.45 -41.25'; '18.06 29.01'; '12.14 -1.657'; '14.98 -12.07'; '7.251 -6.852'; '24.63 2.329'; '35.5 -0.8054'; '38.87 -31.54'; '2.002 18.4'};
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Coverage Area & Node Allocation')
            xlabel(app.UIAxes, 'X/m')
            ylabel(app.UIAxes, 'Y/m')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'WiFi/LTE Configurations';
            app.Panel2 = uipanel(app.Tab2);
            app.Panel2.Title = 'WiFi Configuration';
            app.ButtonGroup = uibuttongroup(app.Panel2);
            app.ButtonGroup.Title = 'Protocol Version';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = '802.11 a';
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = '802.11 n ';
            app.RadioButton2.Value = true;
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Text = '802.11 ac';
            app.ButtonGroup2 = uibuttongroup(app.Panel2);
            app.ButtonGroup2.Title = 'Channel Bandwidth';
            app.RadioButton4 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton4.Text = '20   MHz';
            app.RadioButton4.Value = true;
            app.RadioButton5 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton5.Text = '40   MHz';
            app.RadioButton6 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton6.Text = '80   MHz';
            app.RadioButton7 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton7.Text = '160 MHz';
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'Transmit Power for WiFi devices (dBm)';
            app.NumericEditField6 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField6.Limits = [-10 100];
            app.NumericEditField6.Value = 17;
            app.LabelDropDown3 = uilabel(app.Panel2);
            app.LabelDropDown3.Text = 'Modulation and Coding Scheme (MCS)';
            app.DropDown3 = uidropdown(app.Panel2);
            app.DropDown3.Items = {'00', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31'};
            app.DropDown3.Value = '00';
            app.Panel4 = uipanel(app.Tab2);
            app.Panel4.Title = 'LTE Configuration';
            app.LabelDropDown2 = uilabel(app.Panel4);
            app.LabelDropDown2.Text = 'CQI Report Periodicity';
            app.DropDown2 = uidropdown(app.Panel4);
            app.DropDown2.Items = {'2', '5', '10', '16', '20', '32', '40', '64', '80', '128', '160'};
            app.DropDown2.Value = '10';
            app.Label5 = uilabel(app.Panel4);
            app.Label5.Text = 'Transmit Power for LTE devices (dBm)';
            app.NumericEditField8 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField8.Limits = [-10 100];
            app.NumericEditField8.Value = 17;
            app.LabelDropDown4 = uilabel(app.Panel4);
            app.LabelDropDown4.Text = 'ABS Configuration';
            app.DropDown4 = uidropdown(app.Panel4);
            app.DropDown4.Items = {'ABS0', 'ABS1', 'ABS5'};
            app.DropDown4.ValueChangedFcn = createCallbackFcn(app, @DropDown4ValueChanged, true);
            app.DropDown4.Value = 'ABS1';
            app.LabelDropDown = uilabel(app.Panel4);
            app.LabelDropDown.Text = 'Number of Resource Blocks';
            app.DropDown = uidropdown(app.Panel4);
            app.DropDown.Items = {'6', '15', '25', '50', '75', '100'};
            app.DropDown.Value = '100';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Simulation Settings & Results';
            app.Button3 = uibutton(app.Tab3, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartSimulationButtonPushed, true);
            app.Button3.Text = 'Start Simulation';
            app.UIAxes2 = uiaxes(app.Tab3);
            title(app.UIAxes2, 'Signal Strength')
            xlabel(app.UIAxes2, 'Sample')
            ylabel(app.UIAxes2, 'Received Signal Strength (mW)')
            app.UIAxes3 = uiaxes(app.Tab3);
            title(app.UIAxes3, 'Coverage Area & Node Allocation')
            xlabel(app.UIAxes3, 'X/m')
            ylabel(app.UIAxes3, 'Y/m')
            app.Button4 = uibutton(app.Tab3, 'push');
            app.Button4.Text = 'Reset';
            app.LabelNumericEditField9 = uilabel(app.Tab3);
            app.LabelNumericEditField9.Text = 'Iterations';
            app.IterationEditField = uieditfield(app.Tab3, 'numeric');
            app.IterationEditField.ValueChangedFcn = createCallbackFcn(app, @NumericEditFieldIterationValueChanged, true);
            app.IterationEditField.Limits = [1 200];
            app.IterationEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = EFiSketch

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end